classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HDRImageViewerUIFigure      matlab.ui.Figure
        BiasSliderLabel             matlab.ui.control.Label
        BiasSlider                  matlab.ui.control.Slider
        BiasEditField               matlab.ui.control.NumericEditField
        UIAxes                      matlab.ui.control.UIAxes
        RunButton                   matlab.ui.control.Button
        GammaSliderLabel            matlab.ui.control.Label
        GammaSlider                 matlab.ui.control.Slider
        GammaEditField              matlab.ui.control.NumericEditField
        BrowseButton                matlab.ui.control.Button
        AdaptiveLogarithmicMappingForDisplayingHighContrastScenesLabel  matlab.ui.control.Label
        ExitButton                  matlab.ui.control.Button
        SaveAsButton                matlab.ui.control.Button
        ResettoDefaultValuesButton  matlab.ui.control.Button
    end


    properties (Access = public)
        fullName='memorial.hdr'; % Description
        defaultFullName = 'memorial.hdr';
        img;
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxes.Visible = 'off';
        end

        % Callback function: BiasSlider, GammaSlider, RunButton
        function RunButtonPushed(app, event)
            app.BiasEditField.Value = app.BiasSlider.Value;
            app.GammaEditField.Value = app.GammaSlider.Value;
            
            app.img = main(app.BiasSlider.Value, app.GammaSlider.Value, app.fullName);
            
            % Remove title, axis labels, and tick labels
            title(app.UIAxes, []);
            xlabel(app.UIAxes, []);
            ylabel(app.UIAxes, []);
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};
            imshow(app.img,'parent',app.UIAxes);
            
        end

        % Value changed function: BiasEditField
        function BiasEditFieldValueChanged(app, event)
            value = app.BiasEditField.Value;
            app.BiasSlider.Value = value;
            app.RunButtonPushed;
        end

        % Value changed function: GammaEditField
        function GammaEditFieldValueChanged(app, event)
            value = app.GammaEditField.Value;
            app.GammaSlider.Value = value;
            app.RunButtonPushed;
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
             [filename,filepath] = uigetfile({'*.hdr'}, 'Select File to Open');
              app.fullName = [filepath, filename];
              app.HDRImageViewerUIFigure.Visible = 'off';
              app.HDRImageViewerUIFigure.Visible = 'on';
              if(app.fullName(1) == 0 || app.fullName(2) ==0)
                  app.fullName = app.defaultFullName;
              else
                  app.defaultFullName = app.fullName;
              end
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: SaveAsButton
        function SaveAsButtonPushed(app, event)
            [filename,filepath] = uiputfile('*.jpg', 'Save As jpg');
            app.HDRImageViewerUIFigure.Visible = 'off';
            app.HDRImageViewerUIFigure.Visible = 'on';
            if~(filename == 0)
                saveFile(app.img,[filepath,filename]); 
            end
        end

        % Button pushed function: ResettoDefaultValuesButton
        function ResettoDefaultValuesButtonPushed(app, event)
            app.BiasSlider.Value = 0.85;
            app.GammaSlider.Value = 2.2;
            app.BiasEditField.Value = 0.85;
            app.GammaEditField.Value = 2.2;
            app.RunButtonPushed;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HDRImageViewerUIFigure = uifigure;
            app.HDRImageViewerUIFigure.Name = 'HDR Image Viewer';
            app.BiasSliderLabel = uilabel(app.HDRImageViewerUIFigure);
            app.BiasSliderLabel.Text = 'Bias';
            app.BiasSlider = uislider(app.HDRImageViewerUIFigure);
            app.BiasSlider.Limits = [0 1];
            app.BiasSlider.MajorTicks = [];
            app.BiasSlider.MajorTickLabels = {};
            app.BiasSlider.ValueChangedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.BiasSlider.MinorTicks = [];
            app.BiasSlider.Value = 0.85;
            app.BiasEditField = uieditfield(app.HDRImageViewerUIFigure, 'numeric');
            app.BiasEditField.Limits = [0 1];
            app.BiasEditField.ValueDisplayFormat = '%.2f';
            app.BiasEditField.ValueChangedFcn = createCallbackFcn(app, @BiasEditFieldValueChanged, true);
            app.BiasEditField.Value = 0.85;
            app.UIAxes = uiaxes(app.HDRImageViewerUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.RunButton = uibutton(app.HDRImageViewerUIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.GammaSliderLabel = uilabel(app.HDRImageViewerUIFigure);
            app.GammaSliderLabel.Text = 'Gamma';
            app.GammaSlider = uislider(app.HDRImageViewerUIFigure);
            app.GammaSlider.Limits = [1 4];
            app.GammaSlider.MajorTicks = [];
            app.GammaSlider.MajorTickLabels = {};
            app.GammaSlider.ValueChangedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.GammaSlider.MinorTicks = [];
            app.GammaSlider.Value = 2.2;
            app.GammaEditField = uieditfield(app.HDRImageViewerUIFigure, 'numeric');
            app.GammaEditField.Limits = [1 4];
            app.GammaEditField.ValueDisplayFormat = '%.2f';
            app.GammaEditField.ValueChangedFcn = createCallbackFcn(app, @GammaEditFieldValueChanged, true);
            app.GammaEditField.Value = 2.2;
            app.BrowseButton = uibutton(app.HDRImageViewerUIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.AdaptiveLogarithmicMappingForDisplayingHighContrastScenesLabel = uilabel(app.HDRImageViewerUIFigure);
            app.AdaptiveLogarithmicMappingForDisplayingHighContrastScenesLabel.Text = 'Adaptive Logarithmic Mapping For Displaying High Contrast Scenes ';
            app.ExitButton = uibutton(app.HDRImageViewerUIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.SaveAsButton = uibutton(app.HDRImageViewerUIFigure, 'push');
            app.SaveAsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAsButtonPushed, true);
            app.SaveAsButton.Text = 'Save As';
            app.ResettoDefaultValuesButton = uibutton(app.HDRImageViewerUIFigure, 'push');
            app.ResettoDefaultValuesButton.ButtonPushedFcn = createCallbackFcn(app, @ResettoDefaultValuesButtonPushed, true);
            app.ResettoDefaultValuesButton.Text = 'Reset to Default Values';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HDRImageViewerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HDRImageViewerUIFigure)
        end
    end
end